classdef Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        byKenanBanalandDonHermosuraLabel  matlab.ui.control.Label
        HighFrequencyNoiseRemovalusingFIRandIIRFiltersLabel  matlab.ui.control.Label
        mseIIR               matlab.ui.control.Button
        mseFIR               matlab.ui.control.Button
        SpectrumIIR          matlab.ui.control.Button
        SpectrumFIR          matlab.ui.control.Button
        SpectrogramIIR       matlab.ui.control.Button
        SpectrogramFIR       matlab.ui.control.Button
        InfoButton           matlab.ui.control.Button
        LampIIR              matlab.ui.control.Lamp
        LampFIR              matlab.ui.control.Lamp
        LampNA               matlab.ui.control.Lamp
        LampOA               matlab.ui.control.Lamp
        RValueDisplay        matlab.ui.control.Label
        IIRRVALUESlider      matlab.ui.control.Slider
        IIRRValueLabel       matlab.ui.control.Label
        IIRAUDIOButton       matlab.ui.control.Button
        FIRAUDIOButton       matlab.ui.control.Button
        NOISYAUDIOButton     matlab.ui.control.Button
        ORIGINALAUDIOButton  matlab.ui.control.Button
        CLEARButton          matlab.ui.control.Button
        IMPORTButton         matlab.ui.control.Button
        Label                matlab.ui.control.Label
        MagIIR               matlab.ui.control.UIAxes
        TimeDomainIIR        matlab.ui.control.UIAxes
        PhaseIIR             matlab.ui.control.UIAxes
        MagFIR               matlab.ui.control.UIAxes
        MagnitudeNA          matlab.ui.control.UIAxes
        MagnitudeOA          matlab.ui.control.UIAxes
        TimeDomainFIR        matlab.ui.control.UIAxes
        PhaseFIR             matlab.ui.control.UIAxes
        TimeDomainNA         matlab.ui.control.UIAxes
        SpectrogramNA        matlab.ui.control.UIAxes
        TimeDomainOA         matlab.ui.control.UIAxes
        SpectrogramOA        matlab.ui.control.UIAxes
    end

    
    methods (Access = public)

    end
    properties (Access = public)
        og_Audio
        fullFileName
        IIRpublicAudio
        FIRpublicAudio
        sampleRate
        firCoefficients
        iirCoefficients
        toggle = true;
        toggle2= true;
        toggle3= true;
        toggle4= true;
        toggle5= true;
        toggle6= true;
        maxMSEFIR;
        maxMSEIIR;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: IMPORTButton, SpectrogramOA
        function IMPORTButtonPushed(app, event)
         % Allow user to select an audio file
        [file, path] = uigetfile({'*.wav'; '*.mp3'}, 'Select an Audio File');
        
        % If the user cancels file selection, exit the function
        if isequal(file, 0)
            return;
        end
        
        % Full path of the selected file
        app.fullFileName = fullfile(path, file);
        app.toggle = true;
        app.toggle2= true;
        app.toggle3= true;
        app.toggle4= true;
        app.toggle5= true;
        app.toggle6= true;
        app.SpectrogramFIR.Text = "SPECTROGRAM FIR";
        app.SpectrogramIIR.Text = "SPECTROGRAM IIR";
        app.SpectrumFIR.Text = "MAG SPECTRUM FIR";
        app.SpectrumIIR.Text = "MAG SPECTRUM IIR";
        app.mseFIR.Text = "MSE FIR";
        app.mseIIR.Text = "MSE IIR";

        % Load the audio file
        [original_audio, sample_rate] = audioread(app.fullFileName);
        app.og_Audio = original_audio;
        % Plot the audio spectra
        plotAudioSpectra(app.SpectrogramOA, original_audio, sample_rate, "Original Audio");
        plotTimeDomain(app.TimeDomainOA, original_audio, sample_rate, "Original Audio");
        plotMagnitudeSpectrum(app.MagnitudeOA, original_audio, sample_rate, "Original Audio");
        

        %% Add Noise to Original File

        % Define noise frequencies in Hz and amplitudes
        noise_freqs = [1500, 3060, 3510, 3569.9, 3920, 5180, 5679.9, 6040, 6129.9, 6369.9];
        noise_amps = 0.1 * ones(size(noise_freqs));  % Set amplitude for each noise component
        
        % Generate noise signal based on specified frequencies and amplitudes
        time_vector = (0:length(original_audio)-1) / sample_rate; % Time vector for signal
        noise_signal = zeros(size(original_audio));  % Initialize noise signal
        for i = 1:length(noise_freqs)
            noise_signal = noise_signal + noise_amps(i) * sin(2 * pi * noise_freqs(i) * time_vector');
        end
        
        % Combine noise with the original audio signal
        noisy_audio = original_audio + noise_signal;

        % Save the noisy audio to a file
        audiowrite('NoisyAudio.wav', noisy_audio, sample_rate);
        
        % Calculate the Fourier Transform to identify spectral locations of noise
        N = length(noisy_audio);
        
        plotMagnitudeSpectrum(app.MagnitudeNA,noisy_audio,sample_rate, "Noisy Audio");
        plotAudioSpectra(app.SpectrogramNA, noisy_audio, sample_rate, "Noisy Audio");
        plotTimeDomain(app.TimeDomainNA, noisy_audio, sample_rate, "Noisy Audio");

        % Find noise peaks in the positive half of the spectrum
        % noisy_audio_fft = fft(noisy_audio);
        % frequency_vector = (0:N-1) * (sample_rate / N); % Frequency vector for FFT
        % [~, noise_peaks] = findpeaks(abs(noisy_audio_fft((N/2+1):end)), frequency_vector((N/2+1):end), 'MinPeakHeight', 0.01);
        % Display identified noise frequencies
        % disp("Frequencies of Noise (Hz):")
        % disp(noise_peaks);
        % disp("Discrete Normalized Frequencies of Noise:")
        % disp(noise_peaks / sample_rate);

        %% START Filtering NoisyAudio
        % Reload the noisy audio file for filtering steps
        % Define radii for IIR filters
        [noisy_audio, sample_rate] = audioread('NoisyAudio.wav');
        
        
        L = length(noisy_audio);
        noisy_audio_fft = fftshift(fft(noisy_audio)) / L;
        frequency_vector = -sample_rate/2:sample_rate/L:sample_rate/2 - sample_rate/L;
        
        [notused, noise_peaks] = findpeaks(abs(noisy_audio_fft(( ...
            length(noisy_audio_fft)+1)/2:end)), ...
            frequency_vector((length(noisy_audio_fft)+1)/2:end), ...
            'MinPeakHeight', 0.01);
        fir_coefficients = 1;  % Start with 1 as the initial coefficient

        for i = 1:length(noise_peaks)
            % Calculate the cosine term for the current noise frequency
            cosine_term = 2 * cos(2 * pi * noise_peaks(i) / sample_rate);

            % Update FIR coefficients by convolving with the current term (z^2 - 2*cos(w)*z + 1)
            fir_coefficients = conv(fir_coefficients, [1, -cosine_term, 1]);
        end
        %% FIR FILTER
        
        plotphase(app.PhaseFIR,1,fir_coefficients,"FIR Filter")

        plotMag(app.MagFIR,fir_coefficients,1, "FIR Filter")
        filtered_fir_audio = filter(fir_coefficients, 1, noisy_audio);
        audiowrite("FIRaudio_filtered.wav", filtered_fir_audio / ...
            max(abs(filtered_fir_audio(:))) * 0.5, sample_rate)
        plotTimeDomain(app.TimeDomainFIR, noisy_audio, sample_rate, "FIR Filter");
        
        %% IIR FILTER
        radius_2 = app.IIRRVALUESlider.Value;
        iir_denom_2 = 1;  % Start with the constant polynomial 1
        for i = 1:length(noise_peaks)
            % Define the current term as a numeric polynomial [1, -2 * r * cos(theta), r^2]
            theta = 2 * pi * noise_peaks(i) / sample_rate;
            current_term = [1, -2 * radius_2 * cos(theta), radius_2^2];
            
            % Multiply current term with the existing polynomial
            iir_denom_2 = conv(iir_denom_2, current_term);
        end
        % iir_denom_2 is now the numeric polynomial coefficients for the IIR filter
        iir_coefficients_2 = iir_denom_2;
        filtered_iir_audio_2 = filter(fir_coefficients, iir_coefficients_2, noisy_audio);

        % Make audio file for IIR Filter
        audiowrite("IIR2audio_filtered.wav", filtered_iir_audio_2 / ...
            max(abs(filtered_iir_audio_2(:))) * 0.5, sample_rate)
        % Plot Magnitude, Phase, Time Domain
        plotMag(app.MagIIR,fir_coefficients, iir_coefficients_2,  "IIR Filter")
        plotphase(app.PhaseIIR,iir_coefficients_2, fir_coefficients,  "IIR Filter")
        plotTimeDomain(app.TimeDomainIIR,  filtered_iir_audio_2 / ...
            max(abs(filtered_iir_audio_2(:))) * 0.5, sample_rate, "IIR Filter")

        % Store Audio File and Coefficients as Public Variables for
        % Plotting later
        app.IIRpublicAudio = filtered_iir_audio_2 / ...
            max(abs(filtered_iir_audio_2(:))) * 0.5;
        app.sampleRate = sample_rate;
        app.FIRpublicAudio = filtered_fir_audio / ...
            max(abs(filtered_fir_audio(:))) * 0.5;
        app.firCoefficients = fir_coefficients;
        app.iirCoefficients = iir_coefficients_2;
        %% functions to plot
        function plotMag(app, fir_coefficients,num, str)
            % Define frequency range for the phase plot
            w = 0:0.001:2*pi;
        
            % Compute the frequency response of the FIR filter
            H = freqz(fir_coefficients, num, w);
        
            % Plot phase response
            mag_response = abs(H);  % Get phase angle of the frequency response
        
            % Plot on the designated UIAxes in the app
            plot(app, w/pi, mag_response);
            title(app, 'Magnitude Response of '+str);
            xlabel(app, 'Frequency \times\pi ');
            ylabel(app, 'Magnitude');
            xlim(app, [0 2])
            grid(app, 'on');
        end
        function plotphase(app, fir_coefficients,num,str)
            % Define frequency range for the phase plot
            w = 0:0.001:2*pi;
        
            % Compute the frequency response of the FIR filter
            H = freqz(fir_coefficients, num, w);
        
            % Plot phase response
            phase_response = angle(H);  % Get phase angle of the frequency response
        
            % Plot on the designated UIAxes in the app
            plot(app, w/pi, phase_response);
            title(app, 'Phase Response of '+str);
            xlabel(app, 'Frequency \times\pi ');
            ylabel(app, 'Phase');
            grid(app, 'on');
            ylim(app, [-4 4]);
            xlim(app, [0 2])
        end
        % Function to plot spectrogram, time-domain, and magnitude spectra
        function plotAudioSpectra(app, audio, sample_rate, str)
            % Plot the Spectrogram in SpectrogramOA
            [S,F,T] = spectrogram(audio, 256, 200, 256, sample_rate, 'yaxis');
            imagesc(app, T, F, log(1+abs(S)) ); %plot the log spectrum
            set(app,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
            title(app, 'Spectrogram of '+str);
            xlabel(app, 'Time ');
            ylabel(app, 'Frequency ');
            ylim(app, [0 inf]);
            xlim(app, [0 T(end)])
            grid(app, 'on');
        end
        function plotTimeDomain(app, audio, sample_rate, str)
            % Plot the Time Domain in TimeDomainOA
            [S,F,T] = spectrogram(audio, 256, 200, 256, sample_rate, 'yaxis');
            imagesc(app, T, F, log(1+abs(S)) ); %plot the log spectrum
            timeVector = (0:length(audio) - 1) / sample_rate;
            plot(app, timeVector, audio);
            title(app, 'Time Domain of '+str);
            xlabel(app, 'Time ');
            ylabel(app, 'Amplitude');
            xlim(app, [0 T(end)])
            grid(app, 'on');
            % Set the y-axis limit dynamically based on the amplitude of the signal
            minAmplitude = min(audio);
            maxAmplitude = max(audio);
            ylim(app, [minAmplitude maxAmplitude]);
        end
        function plotMagnitudeSpectrum(app, audio, sample_rate, str)
            % Plot the Magnitude Spectrum in MagnitudeOA
            audio_fft = fft(audio);
            n = length(audio);
            f = (0:n/2-1) * (sample_rate / n);  % Frequency vector up to Nyquist frequency
            magnitude = abs(audio_fft(1:n/2));  % Magnitude of the FFT
            plot(app, f, magnitude);
            title(app, 'Magnitude Spectrum of '+str);
            xlabel(app, 'Frequency');
            ylabel(app, 'Magnitude');
            grid(app, 'on');
            xlim(app, [0 f(end)])
        end

        end

        % Button pushed function: ORIGINALAUDIOButton
        function ORIGINALAUDIOButtonPushed(app, event)
            if isempty(app.fullFileName)
                uialert(app.UIFigure, 'No audio file selected.', 'Error');
                return;
            end
            
            % Load the audio data and sample rate
            [original_audio, sample_rate] = audioread(app.fullFileName);
            
            % Create an audioplayer object
            player = audioplayer(original_audio, sample_rate);
            
            % Change the lamp color to red to indicate the audio is playing
            app.LampOA.Color = 'red';
            
            % Play the audio (playblocking waits until the audio is finished)
            playblocking(player);
            
            % Change the lamp color back to green to indicate the audio has finished
            app.LampOA.Color = 'green';
        end

        % Button pushed function: NOISYAUDIOButton
        function NOISYAUDIOButtonPushed(app, event)
            if isempty(app.fullFileName)
                uialert(app.UIFigure, 'No audio file selected.', 'Error');
                return;
            end
            
            % Load the audio data and sample rate
            [original_audio, sample_rate] = audioread('NoisyAudio.wav');
            
            % Create an audioplayer object
            player = audioplayer(original_audio, sample_rate);
            
            % Change the lamp color to red to indicate the audio is playing
            app.LampNA.Color = 'red';
            
            % Play the audio (playblocking waits until the audio is finished)
            playblocking(player);
            
            % Change the lamp color back to green to indicate the audio has finished
            app.LampNA.Color = 'green';
        end

        % Button pushed function: FIRAUDIOButton
        function FIRAUDIOButtonPushed(app, event)
            if isempty(app.fullFileName)
                uialert(app.UIFigure, 'No audio file selected.', 'Error');
                return;
            end
            
            % Load the audio data and sample rate
            [original_audio, sample_rate] = audioread('FIRaudio_filtered.wav');
            
            % Create an audioplayer object
            player = audioplayer(original_audio, sample_rate);
            
            % Change the lamp color to red to indicate the audio is playing
            app.LampFIR.Color = 'red';
            
            % Play the audio (playblocking waits until the audio is finished)
            playblocking(player);
            
            % Change the lamp color back to green to indicate the audio has finished
            app.LampFIR.Color = 'green';
        end

        % Button pushed function: IIRAUDIOButton
        function IIRAUDIOButtonPushed(app, event)
            if isempty(app.fullFileName)
                uialert(app.UIFigure, 'No audio file selected.', 'Error');
                return;
            end
            
            % Load the audio data and sample rate
            [original_audio, sample_rate] = audioread('IIR2audio_filtered.wav');
            
            % Create an audioplayer object
            player = audioplayer(original_audio, sample_rate);
            
            % Change the lamp color to red to indicate the audio is playing
            app.LampIIR.Color = 'red';
            
            % Play the audio (playblocking waits until the audio is finished)
            playblocking(player);
            
            % Change the lamp color back to green to indicate the audio has finished
            app.LampIIR.Color = 'green';
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            app.fullFileName = [];
            cla(app.MagIIR);
            cla(app.MagFIR);
            cla(app.MagnitudeNA);
            cla(app.MagnitudeOA);
            cla(app.PhaseFIR);
            cla(app.PhaseIIR);
            cla(app.SpectrogramNA);
            cla(app.SpectrogramOA);
            cla(app.TimeDomainNA);
            cla(app.TimeDomainOA);
            cla(app.TimeDomainFIR);
            cla(app.TimeDomainIIR);
        end

        % Value changed function: IIRRVALUESlider
        function IIRRVALUESliderValueChanged(app, event)
            value = app.IIRRVALUESlider.Value;
            % Display the value in RValueDisplay with two decimal places
            app.RValueDisplay.Text = sprintf('%.2f', value);
        end

        % Value changing function: IIRRVALUESlider
        function IIRRVALUESliderValueChanging(app, event)
            value = app.IIRRVALUESlider.Value;
            % Display the value in RValueDisplay with two decimal places
            app.RValueDisplay.Text = sprintf('%.2f', value);
            
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            uialert(app.UIFigure, ['This MATLAB App solves the high frequency (1.5kHz to 6.4kHz) noise problem with an interactive tool by analyzing and' ...
                ' filtering noisy audio signals in which audio files could be imported into the system, noise introduced ' ...
                'with predefined high frequencies, ' ...
                'and the resulting signal can be visualized in both time as well as frequency domains.'], 'Information');
        end

        % Button pushed function: SpectrogramFIR
        function SpectrogramFIRButtonPushed(app, event)
            if isempty(app.fullFileName)
                uialert(app.UIFigure, 'No audio file selected.', 'Error');
                return;
            end
         %if intially clicked
         if (app.toggle)
            plotAudioSpectra(app.PhaseFIR, app.FIRpublicAudio, app.sampleRate,"FIR Audio");
            app.SpectrogramFIR.Text = "PHASE FIR";
         %else
         else
            plotphase(app.PhaseFIR,1,app.firCoefficients,"FIR Filter");
            app.SpectrogramFIR.Text = "SPECTROGRAM FIR";
         end
         app.toggle = ~app.toggle;
        function plotAudioSpectra(app, audio, sample_rate, str)
            % Plot the Spectrogram in SpectrogramOA
            [S,F,T] = spectrogram(audio, 256, 200, 256, sample_rate, 'yaxis');
            imagesc(app, T, F, log(1+abs(S)) ); %plot the log spectrum
            set(app,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
            title(app, "Spectrogram of "+str);
            xlabel(app, 'Time ');
            ylabel(app, 'Frequency ');
            ylim(app, [0 inf]);
            xlim(app, [0 T(end)])
            grid(app, 'on');
        end
        function plotphase(app, fir_coefficients,num,str)
            % Define frequency range for the phase plot
            w = 0:0.001:2*pi;
        
            % Compute the frequency response of the FIR filter
            H = freqz(fir_coefficients, num, w);
        
            % Plot phase response
            phase_response = angle(H);  % Get phase angle of the frequency response
        
            % Plot on the designated UIAxes in the app
            plot(app, w/pi, phase_response);
            title(app, "Phase Response of "+str);
            xlabel(app, 'Frequency \times\pi ');
            ylabel(app, 'Phase');
            ylim(app, [-4 4]);
            xlim(app, [0 2])
            grid(app, 'on');
        end
        end

        % Button pushed function: SpectrogramIIR
        function SpectrogramIIRButtonPushed(app, event)
            if isempty(app.fullFileName)
                uialert(app.UIFigure, 'No audio file selected.', 'Error');
                return;
            end
         %if intially clicked
         if (app.toggle2)
            plotAudioSpectra(app.PhaseIIR, app.IIRpublicAudio, app.sampleRate,"IIR Audio");
            app.SpectrogramIIR.Text = "PHASE IIR";
         %else
         else
            plotphase(app.PhaseIIR, app.iirCoefficients, app.firCoefficients, "IIR Filter");
            app.SpectrogramIIR.Text = "SPECTROGRAM IIR";
         end
         app.toggle2 = ~app.toggle2;
        function plotAudioSpectra(app, audio, sample_rate, str)
            % Plot the Spectrogram in SpectrogramOA
            [S,F,T] = spectrogram(audio, 256, 200, 256, sample_rate, 'yaxis');
            imagesc(app, T, F, log(1+abs(S)) ); %plot the log spectrum
            set(app,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
            title(app, "Spectrogram of "+str);
            xlabel(app, 'Time ');
            ylabel(app, 'Frequency ');
            ylim(app, [0 inf]);
            xlim(app, [0 T(end)])
            grid(app, 'on');
        end
        function plotphase(app, fir_coefficients,num,str)
            % Define frequency range for the phase plot
            w = 0:0.001:2*pi;
        
            % Compute the frequency response of the FIR filter
            H = freqz(fir_coefficients, num, w);
        
            % Plot phase response
            phase_response = angle(H);  % Get phase angle of the frequency response
        
            % Plot on the designated UIAxes in the app
            plot(app, w/pi, phase_response);
            title(app, "Phase Response of "+str);
            xlabel(app, 'Frequency \times\pi ');
            ylabel(app, 'Phase');
            ylim(app, [-4 4]);
            xlim(app, [0 2])
            grid(app, 'on');
        end
        end

        % Button pushed function: SpectrumFIR
        function SpectrumFIRButtonPushed(app, event)
            if isempty(app.fullFileName)
                uialert(app.UIFigure, 'No audio file selected.', 'Error');
                return;
            end
         %if intially clicked
         if (app.toggle3)
            plotMagnitudeSpectrum(app.MagFIR, app.FIRpublicAudio, app.sampleRate,"FIR Audio");
            app.SpectrumFIR.Text = "MAG RESPONSE FIR";
         %else
         else
            plotMag(app.MagFIR,app.firCoefficients,1, "FIR Filter");
            app.SpectrumFIR.Text = "MAG SPECTRUM FIR";
         end
         app.toggle3 = ~app.toggle3;
        function plotMagnitudeSpectrum(app, audio, sample_rate, str)
            % Plot the Magnitude Spectrum in MagnitudeOA
            audio_fft = fft(audio);
            n = length(audio);
            f = (0:n/2-1) * (sample_rate / n);  % Frequency vector up to Nyquist frequency
            magnitude = abs(audio_fft(1:n/2));  % Magnitude of the FFT
            plot(app, f, magnitude);
            title(app, 'Magnitude Spectrum of '+str);
            xlabel(app, 'Frequency');
            ylabel(app, 'Magnitude');
            grid(app, 'on');
            xlim(app, [0 f(end)])
        end
        function plotMag(app, fir_coefficients,num, str)
            % Define frequency range for the phase plot
            w = 0:0.001:2*pi;
        
            % Compute the frequency response of the FIR filter
            H = freqz(fir_coefficients, num, w);
        
            % Plot phase response
            mag_response = abs(H);  % Get phase angle of the frequency response
        
            % Plot on the designated UIAxes in the app
            plot(app, w/pi, mag_response);
            title(app, 'Magnitude Response of '+str);
            xlabel(app, 'Frequency \times\pi ');
            ylabel(app, 'Magnitude');
            grid(app, 'on');
            xlim(app, [0 2])
        end
        end

        % Button pushed function: SpectrumIIR
        function SpectrumIIRButtonPushed(app, event)
            if isempty(app.fullFileName)
                uialert(app.UIFigure, 'No audio file selected.', 'Error');
                return;
            end
         %if intially clicked
         if (app.toggle4)
            plotMagnitudeSpectrum(app.MagIIR, app.IIRpublicAudio, app.sampleRate,"IIR Audio");
            app.SpectrumIIR.Text = "MAG RESPONSE IIR";
         %else
         else
            plotMag(app.MagIIR,app.firCoefficients,app.iirCoefficients, "IIR Filter");
            app.SpectrumIIR.Text = "MAG SPECTRUM IIR";
         end 
         app.toggle4 = ~app.toggle4;
        function plotMagnitudeSpectrum(app, audio, sample_rate, str)
            % Plot the Magnitude Spectrum in MagnitudeOA
            audio_fft = fft(audio);
            n = length(audio);
            f = (0:n/2-1) * (sample_rate / n);  % Frequency vector up to Nyquist frequency
            magnitude = abs(audio_fft(1:n/2));  % Magnitude of the FFT
            plot(app, f, magnitude);
            title(app, 'Magnitude Spectrum of '+str);
            xlabel(app, 'Frequency');
            ylabel(app, 'Magnitude');
            grid(app, 'on');
            xlim(app, [0 f(end)])
        end
        function plotMag(app, fir_coefficients,num, str)
            % Define frequency range for the phase plot
            w = 0:0.001:2*pi;
        
            % Compute the frequency response of the FIR filter
            H = freqz(fir_coefficients, num, w);
        
            % Plot phase response
            mag_response = abs(H);  % Get phase angle of the frequency response
        
            % Plot on the designated UIAxes in the app
            plot(app, w/pi, mag_response);
            title(app, 'Magnitude Response of '+str);
            xlabel(app, 'Frequency \times\pi ');
            ylabel(app, 'Magnitude');
            grid(app, 'on');
            xlim(app, [0 2])
        end
        end

        % Button pushed function: mseFIR
        function mseFIRButtonPushed(app, event)
            if isempty(app.fullFileName)
                uialert(app.UIFigure, 'No audio file selected.', 'Error');
                return;
            end
            if (app.toggle5)
                app.maxMSEFIR = 0;
                plotMSE(app.TimeDomainFIR,app.og_Audio,app.FIRpublicAudio,app.sampleRate,"FIR",app.maxMSEFIR);
                app.mseFIR.Text = "TIME DOMAIN FIR";
            else
                plotTimeDomain(app.TimeDomainFIR,app.FIRpublicAudio,app.sampleRate,"FIR");
                app.mseFIR.Text = "MSE FIR";
            end
            app.toggle5=~app.toggle5;
            function plotMSE(app, ogAudio, filterAudio, sample_rate, str, maxMSE)
                % Compute the FFT and magnitude spectra of both signals
                N = length(ogAudio); % Length of the signal
                ogSpectrum = abs(fft(ogAudio, N)); % Original audio spectrum
                filterSpectrum = abs(fft(filterAudio, N)); % Filtered audio spectrum
            
                % Calculate the Mean Squared Error (MSE) between the spectra
                mse = (filterSpectrum-ogSpectrum).^2;
            
                % Frequency axis for plotting up to the Nyquist frequency
                frequencies = linspace(0, sample_rate/2, N/2);
            
                % Plot the MSE
                plot(app, frequencies, mse(1:N/2)); % Use app.UIAxes for plotting in the app context
                set(app,'YDir', 'normal');
                xlabel(app, 'Frequency (Hz)');
                ylabel(app, 'MSE');
                title(app, ['Mean Squared Error: ', str]);
                grid(app, 'on');
                xlim(app, [0 frequencies(end)])
            % Find maxMSE using a for loop
             halfN = floor(N / 2); % Use the largest integer less than or equal to N/2
            %maxMSE = 0; % Initialize maxMSE
            for i = 1:halfN
                if mse(i) > maxMSE
                    maxMSE = mse(i);
                end
            end
            ylim(app, [0 maxMSE]);
            end
        function plotTimeDomain(app, audio, sample_rate, str)
            % Plot the Time Domain in TimeDomainOA
            [S,F,T] = spectrogram(audio, 256, 200, 256, sample_rate, 'yaxis');
            imagesc(app, T, F, log(1+abs(S)) ); %plot the log spectrum
            timeVector = (0:length(audio) - 1) / sample_rate;
            plot(app, timeVector, audio);
            title(app, 'Time Domain of '+str);
            xlabel(app, 'Time ');
            ylabel(app, 'Amplitude');
            xlim(app, [0 T(end)])
            grid(app, 'on');
            % Set the y-axis limit dynamically based on the amplitude of the signal
            minAmplitude = min(audio);
            maxAmplitude = max(audio);
            ylim(app, [minAmplitude maxAmplitude]);
        end
        end

        % Button pushed function: mseIIR
        function mseIIRButtonPushed(app, event)
            if isempty(app.fullFileName)
                uialert(app.UIFigure, 'No audio file selected.', 'Error');
                return;
            end
            if (app.toggle6)
                app.maxMSEIIR = 0;
                plotMSE(app.TimeDomainIIR,app.og_Audio,app.IIRpublicAudio,app.sampleRate,"IIR",app.maxMSEIIR);
                app.mseIIR.Text = "TIME DOMAIN IIR";
            else
                plotTimeDomain(app.TimeDomainIIR,app.IIRpublicAudio,app.sampleRate,"IIR");
                app.mseIIR.Text = "MSE IIR";
            end
            app.toggle6=~app.toggle6;
            function plotMSE(app, ogAudio, filterAudio, sample_rate, str, maxMSE)
                % Compute the FFT and magnitude spectra of both signals
                N = length(ogAudio); % Length of the signal
                ogSpectrum = abs(fft(ogAudio, N)); % Original audio spectrum
                filterSpectrum = abs(fft(filterAudio, N)); % Filtered audio spectrum
            
                % Calculate the Mean Squared Error (MSE) between the spectra
                mse = (filterSpectrum-ogSpectrum).^2;
            
                % Frequency axis for plotting up to the Nyquist frequency
                frequencies = linspace(0, sample_rate/2, N/2);
            
                % Plot the MSE
                plot(app, frequencies, mse(1:N/2)); % Use app.UIAxes for plotting in the app context
                set(app,'YDir', 'normal');
                xlabel(app, 'Frequency (Hz)');
                ylabel(app, 'MSE');
                title(app, ['Mean Squared Error: ', str]);
                grid(app, 'on');
                xlim(app, [0 frequencies(end)])
                % Find maxMSE using a for loop
                halfN = floor(N / 2); % Use the largest integer less than or equal to N/2
                %maxMSE = 0; % Initialize maxMSE
                for i = 1:halfN
                    if mse(i) > maxMSE
                        maxMSE = mse(i);
                    end
                end
                ylim(app, [0 maxMSE]);
            end
        function plotTimeDomain(app, audio, sample_rate, str)
            % Plot the Time Domain in TimeDomainOA
            [S,F,T] = spectrogram(audio, 256, 200, 256, sample_rate, 'yaxis');
            imagesc(app, T, F, log(1+abs(S)) ); %plot the log spectrum
            timeVector = (0:length(audio) - 1) / sample_rate;
            plot(app, timeVector, audio);
            title(app, 'Time Domain of '+str);
            xlabel(app, 'Time ');
            ylabel(app, 'Amplitude');
            xlim(app, [0 T(end)])
            grid(app, 'on');
            % Set the y-axis limit dynamically based on the amplitude of the signal
            minAmplitude = min(audio);
            maxAmplitude = max(audio);
            ylim(app, [minAmplitude maxAmplitude]);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpectrogramOA = uiaxes(app.UIFigure);
            title(app.SpectrogramOA, 'Spectrogram of Original Audio')
            xlabel(app.SpectrogramOA, 'Time')
            ylabel(app.SpectrogramOA, 'Frequency')
            zlabel(app.SpectrogramOA, 'Z')
            app.SpectrogramOA.ButtonDownFcn = createCallbackFcn(app, @IMPORTButtonPushed, true);
            app.TimeDomainOA = uiaxes(app.UIFigure);
            title(app.TimeDomainOA, 'Time Domain of Original Audio')
            xlabel(app.TimeDomainOA, 'Time')
            ylabel(app.TimeDomainOA, 'Amplitude')
            zlabel(app.TimeDomainOA, 'Z')
            app.SpectrogramNA = uiaxes(app.UIFigure);
            title(app.SpectrogramNA, 'Spectrogram of Noisy Audio')
            xlabel(app.SpectrogramNA, 'Time')
            ylabel(app.SpectrogramNA, 'Frequency')
            zlabel(app.SpectrogramNA, 'Z')
            app.TimeDomainNA = uiaxes(app.UIFigure);
            title(app.TimeDomainNA, 'Time Domain of Noisy Audio')
            xlabel(app.TimeDomainNA, 'Time')
            ylabel(app.TimeDomainNA, 'Amplitude')
            zlabel(app.TimeDomainNA, 'Z')
            app.PhaseFIR = uiaxes(app.UIFigure);
            title(app.PhaseFIR, 'Phase Response of FIR Filter')
            xlabel(app.PhaseFIR, 'Frequency\times\pi ')
            ylabel(app.PhaseFIR, 'Phase')
            zlabel(app.PhaseFIR, 'Z')
            app.TimeDomainFIR = uiaxes(app.UIFigure);
            title(app.TimeDomainFIR, 'Time Domain of FIR Filter')
            xlabel(app.TimeDomainFIR, 'Time')
            ylabel(app.TimeDomainFIR, 'Amplitude')
            zlabel(app.TimeDomainFIR, 'Z')
            app.MagnitudeOA = uiaxes(app.UIFigure);
            title(app.MagnitudeOA, 'Magnitude Spectrum of Original Audio')
            xlabel(app.MagnitudeOA, 'Frequency')
            ylabel(app.MagnitudeOA, 'Magnitude')
            zlabel(app.MagnitudeOA, 'Z')
            app.MagnitudeNA = uiaxes(app.UIFigure);
            title(app.MagnitudeNA, 'Magnitude Spectrum of Noisy Audio')
            xlabel(app.MagnitudeNA, 'Frequency')
            ylabel(app.MagnitudeNA, 'Magnitude')
            zlabel(app.MagnitudeNA, 'Z')
            app.MagFIR = uiaxes(app.UIFigure);
            title(app.MagFIR, 'Magnitude Response of FIR Filter')
            xlabel(app.MagFIR, 'Frequency\times\pi ')
            ylabel(app.MagFIR, 'Magnitude')
            zlabel(app.MagFIR, 'Z')
            app.PhaseIIR = uiaxes(app.UIFigure);
            title(app.PhaseIIR, 'Phase Response of IIR Filter')
            xlabel(app.PhaseIIR, 'Frequency\times\pi ')
            ylabel(app.PhaseIIR, 'Phase')
            zlabel(app.PhaseIIR, 'Z')
            app.TimeDomainIIR = uiaxes(app.UIFigure);
            title(app.TimeDomainIIR, 'Time Domain of IIR Filter')
            xlabel(app.TimeDomainIIR, 'Time')
            ylabel(app.TimeDomainIIR, 'Amplitude')
            zlabel(app.TimeDomainIIR, 'Z')
            app.MagIIR = uiaxes(app.UIFigure);
            title(app.MagIIR, 'Magnitude Response of IIR Filter')
            xlabel(app.MagIIR, 'Frequency\times\pi ')
            ylabel(app.MagIIR, 'Magnitude')
            zlabel(app.MagIIR, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' ';
            app.IMPORTButton = uibutton(app.UIFigure, 'push');
            app.IMPORTButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTButtonPushed, true);
            app.IMPORTButton.Text = 'IMPORT';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.ORIGINALAUDIOButton = uibutton(app.UIFigure, 'push');
            app.ORIGINALAUDIOButton.ButtonPushedFcn = createCallbackFcn(app, @ORIGINALAUDIOButtonPushed, true);
            app.ORIGINALAUDIOButton.Text = 'ORIGINAL AUDIO';
            app.NOISYAUDIOButton = uibutton(app.UIFigure, 'push');
            app.NOISYAUDIOButton.ButtonPushedFcn = createCallbackFcn(app, @NOISYAUDIOButtonPushed, true);
            app.NOISYAUDIOButton.Text = 'NOISY AUDIO';
            app.FIRAUDIOButton = uibutton(app.UIFigure, 'push');
            app.FIRAUDIOButton.ButtonPushedFcn = createCallbackFcn(app, @FIRAUDIOButtonPushed, true);
            app.FIRAUDIOButton.Text = 'FIR AUDIO';
            app.IIRAUDIOButton = uibutton(app.UIFigure, 'push');
            app.IIRAUDIOButton.ButtonPushedFcn = createCallbackFcn(app, @IIRAUDIOButtonPushed, true);
            app.IIRAUDIOButton.Text = 'IIR AUDIO';
            app.IIRRValueLabel = uilabel(app.UIFigure);
            app.IIRRValueLabel.Text = 'IIR R VALUE';
            app.IIRRVALUESlider = uislider(app.UIFigure);
            app.IIRRVALUESlider.Limits = [0 1];
            app.IIRRVALUESlider.ValueChangedFcn = createCallbackFcn(app, @IIRRVALUESliderValueChanged, true);
            app.IIRRVALUESlider.ValueChangingFcn = createCallbackFcn(app, @IIRRVALUESliderValueChanging, true);
            app.IIRRVALUESlider.Value = 0.95;
            app.RValueDisplay = uilabel(app.UIFigure);
            app.RValueDisplay.Text = ' 0.95';
            app.LampOA = uilamp(app.UIFigure);
            app.LampNA = uilamp(app.UIFigure);
            app.LampFIR = uilamp(app.UIFigure);
            app.LampIIR = uilamp(app.UIFigure);
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'INFORMATION';
            app.SpectrogramFIR = uibutton(app.UIFigure, 'push');
            app.SpectrogramFIR.ButtonPushedFcn = createCallbackFcn(app, @SpectrogramFIRButtonPushed, true);
            app.SpectrogramFIR.Text = 'SPECTROGRAM FIR';
            app.SpectrogramIIR = uibutton(app.UIFigure, 'push');
            app.SpectrogramIIR.ButtonPushedFcn = createCallbackFcn(app, @SpectrogramIIRButtonPushed, true);
            app.SpectrogramIIR.Text = 'SPECTROGRAM IIR';
            app.SpectrumFIR = uibutton(app.UIFigure, 'push');
            app.SpectrumFIR.ButtonPushedFcn = createCallbackFcn(app, @SpectrumFIRButtonPushed, true);
            app.SpectrumFIR.Text = 'SPECTRUM FIR';
            app.SpectrumIIR = uibutton(app.UIFigure, 'push');
            app.SpectrumIIR.ButtonPushedFcn = createCallbackFcn(app, @SpectrumIIRButtonPushed, true);
            app.SpectrumIIR.Text = 'SPECTRUM IIR';
            app.mseFIR = uibutton(app.UIFigure, 'push');
            app.mseFIR.ButtonPushedFcn = createCallbackFcn(app, @mseFIRButtonPushed, true);
            app.mseFIR.Text = 'FIR MSE';
            app.mseIIR = uibutton(app.UIFigure, 'push');
            app.mseIIR.ButtonPushedFcn = createCallbackFcn(app, @mseIIRButtonPushed, true);
            app.mseIIR.Text = 'IIR MSE';
            app.HighFrequencyNoiseRemovalusingFIRandIIRFiltersLabel = uilabel(app.UIFigure);
            app.HighFrequencyNoiseRemovalusingFIRandIIRFiltersLabel.Text = 'High Frequency Noise Removal using FIR and IIR Filters';
            app.byKenanBanalandDonHermosuraLabel = uilabel(app.UIFigure);
            app.byKenanBanalandDonHermosuraLabel.Text = {'by Kenan Banal '; 'and Don Hermosura '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end